classdef cortical_browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        color_mapDropDown           matlab.ui.control.DropDown
        color_mapDropDownLabel      matlab.ui.control.Label
        InflatedCheckBox            matlab.ui.control.CheckBox
        boundaryDropDown            matlab.ui.control.DropDown
        boundaryDropDownLabel       matlab.ui.control.Label
        ForcecolorlimitsCheckBox    matlab.ui.control.CheckBox
        maxValueEditField           matlab.ui.control.NumericEditField
        maxValueEditFieldLabel      matlab.ui.control.Label
        minValueEditField           matlab.ui.control.NumericEditField
        minValueEditFieldLabel      matlab.ui.control.Label
        vertexEditField             matlab.ui.control.NumericEditField
        vertexEditFieldLabel        matlab.ui.control.Label
        step_sizeEditField          matlab.ui.control.NumericEditField
        step_sizeEditFieldLabel     matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        subjIDEditField             matlab.ui.control.EditField
        subjIDEditFieldLabel        matlab.ui.control.Label
        SUBJECTS_DIREditField       matlab.ui.control.EditField
        SUBJECTS_DIREditFieldLabel  matlab.ui.control.Label
        AsymmetryCheckBox           matlab.ui.control.CheckBox
        HemisphereDropDown          matlab.ui.control.DropDown
        HemisphereDropDownLabel     matlab.ui.control.Label
        MetricDropDown              matlab.ui.control.DropDown
        MetricDropDownLabel         matlab.ui.control.Label
        DepthstepsSlider            matlab.ui.control.Slider
        DepthstepsSliderLabel       matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        ax_plot                     matlab.ui.control.UIAxes
        ax_surf                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        allData % nVertices x nDepths x nMetrics, with nMetrics ordered as the metrics dropdown menu
        currentData % The data that is ready to plot after selecting in Metric dropdown menu.
        surface_plot % the trisurf mesh plot
        my_dot % The vertex identifier
        my_dot_plot % a dot in the depth_vs_metric plot
        metric_plot % Description
        metric_plot_lh
        metric_plot_rh
        depth_limits_mm % Description
        vID = 0% vertex ID
        mindist = 0; % distance from selected point
        lh_metric_index;
        rh_metric_index;
    end
    
    methods (Access = private)
        
        function current_depth = get_current_depth(app)
            current_depth = app.Slider.Value; % Get
        end
        
        function results = refresh_plots(app)
            %fprintf(1,'Refreshing plots\n');


            % check if we have data and surface geometry of the same
            % hemisphere
            hemi = app.HemisphereDropDown.Value;
            this_metric = app.MetricDropDown.Value;
            isWarning = false;
            if ~contains(this_metric,hemi)
              fprintf(1,'WARNING! Hemisphere and metric appear to be from different hemispheres\n');
              isWarning = true;
            end


            vertices = app.surface_plot.Vertices;
            vertex_xyz   = vertices(app.vID,:);
            % Display the vertex index
            %fprintf('Closest vertex index: %d (distance: %1.2f)\n' , p,mindist);
            app.my_dot.XData = vertex_xyz(1);
            app.my_dot.YData = vertex_xyz(2);
            app.my_dot.ZData = vertex_xyz(3);


            this_metric_index = find(strcmp(app.MetricDropDown.Items,app.MetricDropDown.Value));
            lh_metric_str     = regexprep(app.MetricDropDown.Value,'.h_fsLR','lh_fsLR');
            rh_metric_str     = regexprep(app.MetricDropDown.Value,'.h_fsLR','rh_fsLR');
            lh_metric_index   = find(strcmp(app.MetricDropDown.Items,lh_metric_str));
            rh_metric_index   = find(strcmp(app.MetricDropDown.Items,rh_metric_str));


            %this_data = app.currentData(app.vID,:);
            this_data  = squeeze(app.allData(app.vID,:,this_metric_index));
            this_lh_data = squeeze(app.allData(app.vID,:,lh_metric_index));
            this_rh_data = squeeze(app.allData(app.vID,:,rh_metric_index));
            clim = [min(this_data(:)) max(this_data(:))];
            if clim(2) <= clim(1)
               fprintf(1,'colormap issue')
               clim = [min(clim) min(clim)+1];
            end

            if app.ForcecolorlimitsCheckBox.Value == true
              clim_lo = app.minValueEditField.Value;
              clim_hi = app.maxValueEditField.Value;
              clim = [clim_lo clim_hi];
            end

            app.ax_surf.Colormap = colormap(app.color_mapDropDown.Value);
            
            app.ax_surf.CLim = clim;
           

            the_title = sprintf('Vertex: %d (%1.1f, %1.1f, %1.1f)' ,...
                app.vID, vertex_xyz(1), vertex_xyz(2),vertex_xyz(3));
            app.ax_plot.Title.String = the_title;
            app.ax_plot.YLabel.String = app.MetricDropDown.Value;
            app.ax_plot.YLabel.Interpreter = 'none';
            if app.ForcecolorlimitsCheckBox.Value == true
              app.ax_plot.YLim = clim;
            end

            app.metric_plot.YData = this_data;
            this_step = round(app.DepthstepsSlider.Value)-1;
            this_depth = this_step .* app.step_sizeEditField.Value;
            app.my_dot_plot.XData = this_depth;
            app.my_dot_plot.YData = this_data(this_step+1);
            app.ax_plot.XLim = app.depth_limits_mm;
            app.ax_plot.XLabel.String = 'Depth from pial surface (mm)';
            if isWarning
              app.ax_plot.Title.Color = 'red';
              app.ax_plot.Title.String = ['(contralateral data) ' app.ax_plot.Title.String];
            else
              app.ax_plot.Title.Color = 'black';
            end


            if app.AsymmetryCheckBox.Value == true
               app.metric_plot_lh.YData = this_lh_data;
               app.metric_plot_rh.YData = this_rh_data;
               app.metric_plot.Visible = false;
               app.metric_plot_lh.Visible = true;
               app.metric_plot_rh.Visible = true;
            else
               app.metric_plot.Visible = true;
               Ynan = nan(size(this_lh_data));
               app.metric_plot_lh.Visible = false;
               app.metric_plot_rh.Visible = false;
               %app.metric_plot_lh.YData = Ynan;
               %app.metric_plot_rh.YData = Ynan;
            end


            % put in base workspace
            assignin('base','vID',app.vID);
            assignin('base','this_data',this_data);
            assignin('base','this_metric',this_metric);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SUBJECTS_DIR, sID)

        end

        % Callback function: DepthstepsSlider, DepthstepsSlider
        function DepthSliderValueChanged(app, event)
            step = round(app.DepthstepsSlider.Value);
            app.surface_plot.CData = app.currentData(:,step);

            depth = (step-1) .* app.step_sizeEditField.Value;
            app.my_dot_plot.XData = depth;
            this_y = app.metric_plot.YData(step);
            app.my_dot_plot.YData = this_y;

        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
            SUBJECTS_DIR = app.SUBJECTS_DIREditField.Value;
            subjID       = app.subjIDEditField.Value;
            %D = dir(fullfile(SUBJECTS_DIR,subjID,'dwi/*_fixels*/*32k*.tsf'));
            D = dir(fullfile(SUBJECTS_DIR,subjID,'dwi/**/*32k*.tsf')); % recursive search in dwi directory.
            fprintf(1,'SUBJECTS_DIR = %s\n',SUBJECTS_DIR);
            fprintf(1,'subjID       = %s\n',subjID);
            fprintf(1,'  Number of txt files: %d\n',length(D));
            Dcell = {};
            for f = 1 : length(D)
                fullname = fullfile(D(f).folder,D(f).name);
                splitstr = regexp(fullname,[subjID '/'],'split');
                Dcell{f} = splitstr{2};
                %[directory,fixel_dir] = fileparts(D(f).folder);
                %Dcell{f} = [fixel_dir '/' D(f).name];
            end
            app.MetricDropDown.Items = Dcell;
            boundary = app.boundaryDropDown.Value;
            if app.InflatedCheckBox.Value == true
                inflated_str = '_inflated';
            else
                inflated_str = ''; 
            end
            
            f_rh_surfgeom    = fullfile(SUBJECTS_DIR,subjID,['surf/rh_' boundary '_fsLR-32k' inflated_str '.surf.gii']);
            f_lh_surfgeom    = fullfile(SUBJECTS_DIR,subjID,['surf/lh_' boundary '_fsLR-32k' inflated_str '.surf.gii']);
            SURF_LH           = read_surface({f_lh_surfgeom,f_rh_surfgeom});
            SURF_L            = SURF_LH{1};
            SURF_R            = SURF_LH{2};

            hemi = app.HemisphereDropDown.Value;
            if strmatch(hemi,"lh")
              fprintf(1,'Showing left hemisphere\n');
              s = SURF_L;
              sIpsi   = SURF_L;
              sContra = SURF_R;
              thisview = [-90 0];
            else
              fprintf(1,'Showing right hemisphere\n');
              s = SURF_R;
              sIpsi   = SURF_R;
              sContra = SURF_L;
              thisview = [90 0];
            end
            
            dummydata = ones(length(s.vertices),1);
             
            hold(app.ax_surf,'off');
            app.surface_plot = trisurf(...
                s.faces, ...
                s.vertices(:,1), ...
                s.vertices(:,2), ...
                s.vertices(:,3), ...
                dummydata, ...
                'EdgeColor', 'interp',...
                'FaceColor', 'interp',...
                'EdgeAlpha', 0, ...
                'Parent',app.ax_surf,...
                'Tag','the_trisurf');
            hold(app.ax_surf,'on');

            material(app.ax_surf,'dull');
            lighting(app.ax_surf,'gouraud');
            set(app.ax_surf, ...
               'Visible'           , 'off'         , ...
               'DataAspectRatio'   , [1 1 1]       , ...
               'PlotBoxAspectRatio', [1 1 1]       , ...
               'CLim'              , clim          );
            % Add a camlight.
            camlight(app.ax_surf,'local');
            colorbar(app.ax_surf);
            %axis vis3d;
            app.ax_surf.View = thisview;
            app.ax_surf.Clipping = "off";
            app.my_dot = scatter3(app.ax_surf,0,0,0,'red','filled'); hold on;


            % hold(app.UIAxes,'off');
            % app.surface_plot = trisurf(...
            %     sContra.faces, ...
            %     sContra.vertices(:,1), ...
            %     sContra.vertices(:,2), ...
            %     sContra.vertices(:,3), ...
            %     dummydata, ...
            %     'EdgeColor', 'interp',...
            %     'FaceColor', 'interp',...
            %     'EdgeAlpha', 0, ...
            %     'Parent',app.UIAxes,...
            %     'Tag','the_trisurf');
            % hold(app.UIAxes,'on');
            % 
            % material(app.UIAxes,'dull');
            % lighting(app.UIAxes,'gouraud');
            % set(app.UIAxes, ...
            %    'Visible'           , 'off'         , ...
            %    'DataAspectRatio'   , [1 1 1]       , ...
            %    'PlotBoxAspectRatio', [1 1 1]       , ...
            %    'CLim'              , clim          );
            % % Add a camlight.
            % camlight(app.UIAxes,'local');
            % colorbar(app.UIAxes);
            % %axis vis3d;
            % app.UIAxes.View = thisview;
            % app.UIAxes.Clipping = "off";
            % %app.my_dot2 = scatter3(app.UIAxes,0,0,0,'red','filled'); hold on;


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            fprintf(1,'Loading data...\n');
            fs = app.MetricDropDown.Items;
            folder = app.SUBJECTS_DIREditField.Value;
            subjID = app.subjIDEditField.Value;
            for f = 1 : length(fs)
               this_f = fullfile(folder,subjID,fs{f});
               fprintf(1,'Loading %s ...\n',this_f);
               tsf = read_mrtrix_tsf(this_f);
               nStreamlines = length(tsf.data);
               nDepths = 20; %HARDCODED!!!
               M = zeros(nStreamlines,nDepths);
               for s = 1 : nStreamlines
                  d = tsf.data{s}';
                  if length(d) < nDepths
                      d(end+1:nDepths) = NaN;
                  end
                  M(s,:) = d(1:nDepths);
               end
               app.allData(:,:,f) = M;


               %app.allData(:,:,f) = load(this_f);
            end
            fprintf(1,'Finished loading %d files\n',length(fs));
            nDepths = size(app.allData,2);
            %depth_limits = [0 nDepths-1] .* app.step_sizeEditField.Value;
            depth_limits = [1 nDepths];
            app.DepthstepsSlider.Limits = depth_limits; %matlab offset by one

            % Remove invalid data
            %app.allData(app.allData == -1) = NaN; 
            assignin('base','allData',app.allData);
            

            % Add a dummy plot

            app.depth_limits_mm = [0 (nDepths-1).* app.step_sizeEditField.Value];
            
            hold(app.ax_plot,'off');
            X = linspace(app.depth_limits_mm(1),app.depth_limits_mm(2),nDepths);
            Y = linspace(app.depth_limits_mm(1),app.depth_limits_mm(2),nDepths);

            app.metric_plot = plot(app.ax_plot, X,Y,'- k','LineWidth',2);
            hold(app.ax_plot,'on');
            Y = nan(size(X));
            app.metric_plot_rh = plot(app.ax_plot, X, Y,'Color', [0.93 0.69 0.13], 'LineWidth',1.5);
            app.metric_plot_lh = plot(app.ax_plot, X, Y,'Color', [0.00 0.45 0.74], 'LineWidth',1.5);
            app.my_dot_plot    = scatter(app.ax_plot,0,0,'r','filled');

            MetricDropDownValueChanged(app)
            %refresh_plots(app)

        end

        % Value changed function: MetricDropDown
        function MetricDropDownValueChanged(app, event)
            value = app.MetricDropDown.Value;
            %fprintf(1,'++ In MetricDropDownValueChanged\n');
            items = app.MetricDropDown.Items;
            matches = strcmp(items,value);
            metric_index   = find(matches);
            app.currentData = app.allData(:,:,metric_index);
            fprintf(1,'++ Selected %s (%d)\n',value,metric_index);
            assignin('base','currentData',app.currentData);
            assignin('base','metric_index',metric_index);
            assignin('base','f_tsf',value);
            step = round(app.DepthstepsSlider.Value);
            app.surface_plot.CData = app.currentData(:,step);
            
        end

        % Button down function: ax_surf
        function ax_surfButtonDown(app, event)
            %fprintf('You clicked! (triggered by ax_surfButtonDown)\n')
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            %fprintf('You clicked! (triggered by UIFigureButtonDown\n')
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)


            %fprintf('You clicked! (triggered by UIFigureWindowButtonDown\n')
            % Get the clicked point in axes coordinates
            clickedPoint = event.IntersectionPoint;
            
            % Reshape X, Y, Z into vectors for easier processing
            try
                vertices = app.surface_plot.Vertices;
            catch
                return
            end
             
            % Compute the Euclidean distance to all vertices
            distances = vecnorm(vertices - clickedPoint, 2, 2);
            
            % Find the index of the closest vertex
            [app.mindist, p] = min(distances);
            vertex_xyz   = vertices(p,:);
            
            if app.mindist > 3 | isnan(app.mindist)
              return
            end

            app.vertexEditField.Value = p;
            vertexEditFieldValueChanged(app,event);

            % % Display the vertex index
            % %fprintf('Closest vertex index: %d (distance: %1.2f)\n' , p,mindist);
            % app.my_dot.XData = vertex_xyz(1);
            % app.my_dot.YData = vertex_xyz(2);
            % app.my_dot.ZData = vertex_xyz(3);
            % 
            % this_data = app.currentData(p,:);
            % %a = app.currentData;
            % %depths = [0:size(this_data,2)-1] .* app.step_size;
            % % hp = plot(depths,this_data,'Parent',app.ax_plot);
            % % hold on;
            % the_title = sprintf('Closest vertex: %d (%1.1f, %1.1f, %1.1f | distance: %1.2f mm)' ,...
            %     p, vertex_xyz(1), vertex_xyz(2),vertex_xyz(3), mindist);
            % app.ax_plot.Title.String = the_title;
            % app.ax_plot.YLabel.String = app.MetricDropDown.Value;
            % app.ax_plot.YLabel.Interpreter = 'none';
            % app.metric_plot.YData = this_data;
            % this_step = round(app.DepthstepsSlider.Value)-1;
            % this_depth = this_step .* app.step_sizeEditField.Value;
            % app.my_dot_plot.XData = this_depth;
            % app.my_dot_plot.YData = this_data(this_step+1);
            % app.ax_plot.XLim = app.depth_limits_mm;
            % app.ax_plot.XLabel.String = 'Depth from pial surface (mm)';
            % if isWarning
            %   app.ax_plot.Title.Color = 'red';
            % else
            %   app.ax_plot.Title.Color = 'black';
            % end
            % 
            % % put in base workspace
            % assignin('base','p',p);
            % assignin('base','this_data',this_data);
            % assignin('base','this_metric',this_metric);


        end

        % Value changed function: vertexEditField
        function vertexEditFieldValueChanged(app, event)
            app.vID = app.vertexEditField.Value;
            %fprintf(1,'Vertex ID: %d\n',vID);
            refresh_plots(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {419, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DepthstepsSliderLabel = uilabel(app.LeftPanel);
            app.DepthstepsSliderLabel.Text = 'Depth (steps)';
            app.DepthstepsSlider = uislider(app.LeftPanel);
            app.DepthstepsSlider.ValueChangedFcn = createCallbackFcn(app, @DepthSliderValueChanged, true);
            app.DepthstepsSlider.ValueChangingFcn = createCallbackFcn(app, @DepthSliderValueChanged, true);
            app.MetricDropDownLabel = uilabel(app.LeftPanel);
            app.MetricDropDownLabel.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.LeftPanel);
            app.MetricDropDown.Items = {'select'};
            app.MetricDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricDropDownValueChanged, true);
            app.MetricDropDown.Value = 'select';
            app.HemisphereDropDownLabel = uilabel(app.LeftPanel);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.LeftPanel);
            app.HemisphereDropDown.Items = {'rh', 'lh'};
            app.HemisphereDropDown.Value = 'lh';
            app.AsymmetryCheckBox = uicheckbox(app.LeftPanel);
            app.AsymmetryCheckBox.Text = 'Asymmetry';
            app.SUBJECTS_DIREditFieldLabel = uilabel(app.LeftPanel);
            app.SUBJECTS_DIREditFieldLabel.Text = 'SUBJECTS_DIR';
            app.SUBJECTS_DIREditField = uieditfield(app.LeftPanel, 'text');
            app.SUBJECTS_DIREditField.Value = '/misc/lauterbur2/lconcha/Edmonton/fs_edmonton';
            app.subjIDEditFieldLabel = uilabel(app.LeftPanel);
            app.subjIDEditFieldLabel.Text = 'subjID';
            app.subjIDEditField = uieditfield(app.LeftPanel, 'text');
            app.subjIDEditField.Value = 'sub-Mcd005';
            app.RefreshButton = uibutton(app.LeftPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.step_sizeEditFieldLabel = uilabel(app.LeftPanel);
            app.step_sizeEditFieldLabel.Text = 'step_size';
            app.step_sizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.step_sizeEditField.Limits = [0 Inf];
            app.step_sizeEditField.Value = 0.5;
            app.vertexEditFieldLabel = uilabel(app.LeftPanel);
            app.vertexEditFieldLabel.Text = 'vertex';
            app.vertexEditField = uieditfield(app.LeftPanel, 'numeric');
            app.vertexEditField.ValueDisplayFormat = '%d';
            app.vertexEditField.ValueChangedFcn = createCallbackFcn(app, @vertexEditFieldValueChanged, true);
            app.vertexEditField.Value = 1;
            app.minValueEditFieldLabel = uilabel(app.LeftPanel);
            app.minValueEditFieldLabel.Text = 'minValue';
            app.minValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maxValueEditFieldLabel = uilabel(app.LeftPanel);
            app.maxValueEditFieldLabel.Text = 'maxValue';
            app.maxValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maxValueEditField.Value = 1;
            app.ForcecolorlimitsCheckBox = uicheckbox(app.LeftPanel);
            app.ForcecolorlimitsCheckBox.Text = 'Force color limits';
            app.boundaryDropDownLabel = uilabel(app.LeftPanel);
            app.boundaryDropDownLabel.Text = 'boundary';
            app.boundaryDropDown = uidropdown(app.LeftPanel);
            app.boundaryDropDown.Items = {'white', 'pial'};
            app.boundaryDropDown.Value = 'white';
            app.InflatedCheckBox = uicheckbox(app.LeftPanel);
            app.InflatedCheckBox.Text = 'Inflated';
            app.color_mapDropDownLabel = uilabel(app.LeftPanel);
            app.color_mapDropDownLabel.Text = 'color_map';
            app.color_mapDropDown = uidropdown(app.LeftPanel);
            app.color_mapDropDown.Items = {'parula', 'turbo', 'jet', 'hot', 'cool', 'gray'};
            app.color_mapDropDown.Value = 'parula';
            app.RightPanel = uipanel(app.GridLayout);
            app.ax_surf = uiaxes(app.RightPanel);
            title(app.ax_surf, 'Title')
            xlabel(app.ax_surf, 'X')
            ylabel(app.ax_surf, 'Y')
            zlabel(app.ax_surf, 'Z')
            app.ax_surf.ButtonDownFcn = createCallbackFcn(app, @ax_surfButtonDown, true);
            app.ax_plot = uiaxes(app.RightPanel);
            title(app.ax_plot, 'Title')
            xlabel(app.ax_plot, 'X')
            ylabel(app.ax_plot, 'Y')
            zlabel(app.ax_plot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cortical_browser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end